classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        X1EditFieldLabel  matlab.ui.control.Label
        X1EditField       matlab.ui.control.EditField
        X2EditFieldLabel  matlab.ui.control.Label
        X2EditField       matlab.ui.control.EditField
        UIAxes            matlab.ui.control.UIAxes
        RefreshButton     matlab.ui.control.Button
        NSpinnerLabel     matlab.ui.control.Label
        NSpinner          matlab.ui.control.Spinner
        FinishButton      matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        NisDFTlengthItmustbegreaterthanminimumlengthofx1andx2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x1;
        x2;
        N;
        % Description
    end
    
    methods (Access = private)
        
        function results = cconv(app , a , b)
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.X1EditField.Value = '';
            app.X2EditField.Value = '';
            app.NSpinner.Value = 1;
            plot(app.UIAxes,[0],[0]);
            plot(app.UIAxes2,[0],[0]);
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            value = app.X1EditField.Value;
            app.x1 = str2num(value);
            if app.x2
                if app.NSpinner.Value <=1
                    app.N = length(app.x1) + length(app.x2) - 1 ;
                    
                end
                if app.N <= min(length(app.x1) , length(app.x2))
                    app.N = min(length(app.x1) , length(app.x2)) + 1;
                end
                app.NSpinner.Value = app.N;
                x12L = conv(app.x1 , app.x2);
                x12C = abs(ifft(fft(app.x1,app.N).*fft(app.x2,app.N),app.N));
                plot(app.UIAxes ,0:length(x12L)-1 ,x12L , '-*');
                plot(app.UIAxes2 ,0:length(x12C)-1 ,x12C, '-*');
            end
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            value = app.X2EditField.Value;
            app.x2 = str2num(value);
            
            if app.x1
                if app.NSpinner.Value <=1
                    
                    app.N = length(app.x1) + length(app.x2) - 1 ;
                
                    
                end
                if app.N <= min(length(app.x1) , length(app.x2))
                    app.N = min(length(app.x1) , length(app.x2)) + 1;
                end
                app.NSpinner.Value = app.N;
                x12L = conv(app.x1 , app.x2);
                x12C = abs(ifft(fft(app.x1,app.N).*fft(app.x2,app.N),app.N));
                plot(app.UIAxes ,0:length(x12L)-1 ,x12L, '-*');
                plot(app.UIAxes2 ,0:length(x12C)-1 ,x12C, '-*');
            end
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            value = app.NSpinner.Value;
            app.N = value;
            
            
            if app.x1
                if app.x2
                    if app.N <= min(length(app.x1) , length(app.x2))
                        app.N = min(length(app.x1) , length(app.x2)) + 1;
                        app.NSpinner.Value = app.N;
                    end
                    
                    x12L = conv(app.x1 , app.x2);
                    x12C = abs(ifft(fft(app.x1,app.N).*fft(app.x2,app.N),app.N));
                    plot(app.UIAxes ,0:length(x12L)-1 ,x12L, '-*');
                    plot(app.UIAxes2 ,0:length(x12C)-1 ,x12C, '-*');
                end
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'text');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'text');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Linear Convolution')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NSpinnerLabel = uilabel(app.UIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.UIFigure);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 1;
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Circular Convolution')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amlitude')
            app.NisDFTlengthItmustbegreaterthanminimumlengthofx1andx2Label = uilabel(app.UIFigure);
            app.NisDFTlengthItmustbegreaterthanminimumlengthofx1andx2Label.Text = 'N is DFT length : It must be greater than minimum length of x1 and x2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end